classdef CO_SOLVER_v5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ClearButton                 matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        SolvedDataHistroyLabel      matlab.ui.control.Label
        ExportButton                matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        CopyrightZhenkunWangSDIMSUSTechShenzhenChinaLabel  matlab.ui.control.Label
        SolverdevelopedbyLabel      matlab.ui.control.Label
        CIAMLabel                   matlab.ui.control.Label
        CombinatorialOptimizationSolverv55Label  matlab.ui.control.Label
        CurrentiterEditFieldLabel   matlab.ui.control.Label
        CurrentiterEditField        matlab.ui.control.EditField
        timeLabel                   matlab.ui.control.Label
        RuntimesEditField           matlab.ui.control.EditField
        Lamp                        matlab.ui.control.Lamp
        LampLabel                   matlab.ui.control.Label
        StatusLabel                 matlab.ui.control.Label
        LanguageButtonGroup         matlab.ui.container.ButtonGroup
        MatlabButton                matlab.ui.control.ToggleButton
        PythonButton                matlab.ui.control.ToggleButton
        iterationsEditFieldLabel    matlab.ui.control.Label
        capacityEditFieldLabel      matlab.ui.control.Label
        CurrentbestobjValEditFieldLabel  matlab.ui.control.Label
        ProblemButtonGroup          matlab.ui.container.ButtonGroup
        TSPButton                   matlab.ui.control.ToggleButton
        VRPButton                   matlab.ui.control.ToggleButton
        KPButton                    matlab.ui.control.ToggleButton
        OpenDataButton              matlab.ui.control.Button
        AlgorithmsButtonGroup       matlab.ui.container.ButtonGroup
        MoreButton                  matlab.ui.control.RadioButton
        MoreDropDown                matlab.ui.control.DropDown
        CLIMBButton                 matlab.ui.control.RadioButton
        CWButton                    matlab.ui.control.RadioButton
        HPSOButton                  matlab.ui.control.RadioButton
        BruteForceButton            matlab.ui.control.RadioButton
        MonteCarloButton            matlab.ui.control.RadioButton
        GreedyButton                matlab.ui.control.RadioButton
        DPButton                    matlab.ui.control.RadioButton
        GAButton                    matlab.ui.control.RadioButton
        SAButton                    matlab.ui.control.RadioButton
        KNNButton                   matlab.ui.control.RadioButton
        TWOOPTButton                matlab.ui.control.RadioButton
        VNSButton                   matlab.ui.control.RadioButton
        ACOButton                   matlab.ui.control.RadioButton
        GurobiButton                matlab.ui.control.RadioButton
        capacityEditField           matlab.ui.control.EditField
        iterationsEditField         matlab.ui.control.EditField
        timeLimEditField            matlab.ui.control.EditField
        SolveButton                 matlab.ui.control.Button
        CurrentbestobjValEditField  matlab.ui.control.EditField
        timeLimEditFieldLabel       matlab.ui.control.Label
        UIAxes_objVal               matlab.ui.control.UIAxes
        UIAxes_Data                 matlab.ui.control.UIAxes
        UIAxes_Result               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data; % Description
        SolvedData;
        HistoryData = {};

        dairy;

        % extented algorithms for more options:
        MoreAlgorithms = struct( ...
            'TSP_GREEDY', TSP_GREEDY);

%         1-BF, 2-MC, 3-GREEDY, 4-DP, 5-GA, 6-SA, 7-KNN, 8-TWOOPT, 9-VNS,
%         10-ACO, 11-Gurobi 12-HPSO 13-CW 14-CLIMB
        Algorithms = {...
                        {TSP_BF, TSP_MC, TSP_GREEDY, TSP_DP, TSP_GA, TSP_SA, TSP_KNN, TSP_TWOOPT, TSP_VNS, TSP_ACO, 0, 0, 0, 0;...
                        VRP_BF, VRP_MC, VRP_GREEDY, 0, VRP_GA, VRP_SA, 0, VRP_TWOOPT, VRP_VNS, VRP_ACO, 0, VRP_HPSO, VRP_CW, 0;...
                        KP_BF, KP_MC, KP_GREEDY, KP_DP, KP_GA, KP_SA, KP_KNN, KP_TWOOPT, KP_VNS, KP_ACO, 0, 0, 0, 0;},...
                        ...
                        {0, 0, py.TSP_GREEDY.GreedSolution, 0, 0, py.TSP_SA.SASolution, 0, 0, 0, 0, 0, 0, 0, py.TSP_CLIMB.ClimbSolution;...
                        0, 0, 0, 0, 0, 0, 0, 0, 0, 0, py.Gurobi_Class.Gurobi, 0, 0, 0;...
                        0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0};...
                        };
        problemIndex = 2;
        problemName = 'VRP';
        algorithmsIndex = 11;
        algorithmName = 'Gurobi';
        languageIndex = 2;
        languageName = 'Python';
        isSolving = false;
        Algo;

        Timer;
    end

    methods (Access = private)
        function TimerFcn(app,~,~)
            app.RuntimesEditField.Value = double2str(str2double(app.RuntimesEditField.Value) + 0.1);
        end

        function MergedHistoryData = merge_HistoryData(~, HistoryData)
            MergedHistoryData.language = [];
            languageArray = {};
            MergedHistoryData.problem = [];
            problemArray = {};
            MergedHistoryData.n = [];
            MergedHistoryData.algorithm = [];
            algorithmArray = {};
            MergedHistoryData.iter = [];
            MergedHistoryData.runTime = [];
            MergedHistoryData.objVal = [];
            
            for i = 1:size(HistoryData,2)
                SingleData = HistoryData{i};

                languageArray{end+1} = SingleData.language;
                MergedHistoryData.language = languageArray;

                problemArray{end+1} = SingleData.problem;
                MergedHistoryData.problem = problemArray;

                MergedHistoryData.n(end+1) = SingleData.n;

                algorithmArray{end+1} = SingleData.algorithm;
                MergedHistoryData.algorithm = algorithmArray;

                MergedHistoryData.iter(end+1) = SingleData.iter;
                MergedHistoryData.runTime(end+1) = SingleData.runTime;
                MergedHistoryData.objVal(end+1) = SingleData.objVal;
            end

            % transpose to list downward.
            MergedHistoryData.language = MergedHistoryData.language';
            MergedHistoryData.problem = MergedHistoryData.problem';
            MergedHistoryData.n = MergedHistoryData.n';
            MergedHistoryData.algorithm = MergedHistoryData.algorithm';
            MergedHistoryData.iter = MergedHistoryData.iter';
            MergedHistoryData.runTime = MergedHistoryData.runTime';
            MergedHistoryData.objVal = MergedHistoryData.objVal';
        end

        function update_ButtonGroup(app)
            app.BruteForceButton.Visible = "on";
            app.MonteCarloButton.Visible = "on";
            app.GreedyButton.Visible = "on";
            app.DPButton.Visible = "on";
            app.GAButton.Visible = "on";
            app.SAButton.Visible = "on";
            app.KNNButton.Visible = "on";
            app.TWOOPTButton.Visible = "on";
            app.VNSButton.Visible = "on";
            app.ACOButton.Visible = "on";
            app.GurobiButton.Visible = "on";
            app.HPSOButton.Visible = "on";
            app.CWButton.Visible = "on";
            app.CLIMBButton.Visible = "on";
            if app.languageIndex == 1
                if app.problemIndex == 1
                    app.GurobiButton.Visible = "off";
                    app.HPSOButton.Visible = "off";
                    app.CWButton.Visible = "off";
                    app.CLIMBButton.Visible = "off";
                elseif app.problemIndex == 2
                    app.KNNButton.Visible = "off";
                    app.GurobiButton.Visible = "off";
                    app.DPButton.Visible = "off";
                    app.GurobiButton.Visible = "off";
                    app.CLIMBButton.Visible = "off";
                else
                    app.GurobiButton.Visible = "off";
                    app.HPSOButton.Visible = "off";
                    app.CWButton.Visible = "off";
                    app.CLIMBButton.Visible = "off";
                end
            else
                if app.problemIndex == 1
                elseif app.problemIndex == 2
                else
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            disp('Message: Combinatorial Optimization Solver v4.1 has started');
            app.Lamp.Color = [0,1,0];
            app.StatusLabel.Text = 'Please input Data';
            app.MoreDropDown.Visible = "off";
            app.update_ButtonGroup();
        end

        % Button pushed function: OpenDataButton
        function OpenDataButtonPushed(app, event)

                filename = uigetfile('.mat', 'Select .mat data');
                app.Data = load(filename).Data;


                cla(app.UIAxes_Data)
                hold(app.UIAxes_Data,'on')
                scatter(app.UIAxes_Data,app.Data.cx(1),app.Data.cy(1),50,'red','filled','s');
                scatter(app.UIAxes_Data,app.Data.cx(2:end),app.Data.cy(2:end),20,'blue','filled');
                text(app.UIAxes_Data,app.Data.cx(2:end), app.Data.cy(2:end), cellstr(num2str(app.Data.demand(2:end)')),'Fontsize', 14);
                
                app.capacityEditField.Value = num2str(app.Data.capacity);
                app.iterationsEditField.Value = num2str(app.Data.iterations);
                app.timeLimEditField.Value = num2str(app.Data.timeLim);

                app.isSolving = false;
                app.Lamp.Color = [0,1,0];
                app.StatusLabel.Text = 'Ready to solve';
                disp('Message: Input Data: ');
                disp(app.Data);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
       cla(app.UIAxes_objVal);
            cla(app.UIAxes_Result);
            app.RuntimesEditField.Value = '';
            app.CurrentiterEditField.Value = '';

            % set is_solving to true
            app.isSolving = true;
            app.Lamp.Color = [1,1,0];
            app.StatusLabel.Text = 'Solving...';
            pause(0.01);

            % get algorithm
            app.Algo = app.Algorithms{app.languageIndex}{app.problemIndex, app.algorithmsIndex};

            if app.languageIndex == 1
                app.Algo.clear();
            end

            % set GUI componet's handle to algorithm
            if app.languageIndex == 1 % if matlab, set gui
                app.Algo.set_GUI(app.RuntimesEditField, app.CurrentiterEditField, app.CurrentbestobjValEditField, app.UIAxes_objVal, app.UIAxes_Result);
            end

            % set, solve, get, all in one.
            app.SolvedData = struct(app.Algo.get_solved_Data(app.Data));

            disp('Message: Solved Data:')
            disp(app.SolvedData); % for testing purposeD

            % get explict coordinates and graph and objVal from Data
            cx = double(app.SolvedData.cx);
            cy = double(app.SolvedData.cy);
            demand = int64(double(app.SolvedData.demand));
            xi = int64(double(app.SolvedData.xi));
            xj = int64(double(app.SolvedData.xj));
            objVal = app.SolvedData.objVal();

            % clear and hold
            cla(app.UIAxes_Result);
            hold(app.UIAxes_Result,'on');

            G = graph(xi,xj);
            plot(app.UIAxes_Result,G,'XData',cx,'YData',cy,'NodeFontSize',5);
            % draw map
            scatter(app.UIAxes_Result,cx(1),cy(1),50,'red','filled','s');
            scatter(app.UIAxes_Result,cx(2:end),cy(2:end),20,'blue','filled');
            text(app.UIAxes_Result, cx(2:end), cy(2:end)+1, cellstr(num2str(demand(2:end)')),'Fontsize', 14); % 0.5 for a step
            % show objVal
            app.CurrentbestobjValEditField.Value = num2str(objVal);

            % final update of iter
            if app.languageIndex == 1
                app.CurrentiterEditField.Value = num2str(app.Algo.iter);
                app.RuntimesEditField.Value = num2str(app.Algo.get_runTime());
            end

            % done solving, change statuslabel and lamp
            app.isSolving = true;
            app.Lamp.Color = [0,1,0];
            app.StatusLabel.Text = 'Problem Solved!';

            app.SolvedData.language = app.languageName;
            app.SolvedData.problem = app.problemName;
            app.SolvedData.algorithm = app.algorithmName;
            if app.languageIndex == 1 % for matlab
                app.SolvedData.iter = app.Algo.get_iter();
                app.SolvedData.runTime = app.Algo.get_runTime();
            else % for python
                app.SolvedData.iter = 0;
                app.SolvedData.runTime = 0;
            end
            
            app.HistoryData{end+1} = app.SolvedData;
%             disp('Message: Implemented SolvedData');
%             disp(app.SolvedData);
            app.UITable.Data = struct2table(app.merge_HistoryData(app.HistoryData));
            scroll(app.UITable, 'bottom');
        end

        % Value changed function: capacityEditField
        function capacityEditFieldValueChanged(app, event)
            app.Data.capacity = str2double(app.capacityEditField.Value);
        end

        % Value changed function: timeLimEditField
        function timeLimEditFieldValueChanged(app, event)
            app.Data.timeLim = str2double(app.timeLimEditField.Value);
        end

        % Selection changed function: AlgorithmsButtonGroup
        function AlgorithmsButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlgorithmsButtonGroup.SelectedObject;
            switch selectedButton % No break needed
                case app.BruteForceButton
                    disp('Message: BruteForceButton Selected');
%                     app.MessageTextArea.Value = [app.MessageTextArea.Value, 'Message: BruteForceButton Selected', newline];
                    app.algorithmsIndex = 1;
                    app.algorithmName = 'BF';
                    app.MoreDropDown.Visible = "off";
                case app.MonteCarloButton
                    disp('Message: MonteCarloButton Selected');
%                     app.MessageTextArea.Value = [app.MessageTextArea.Value, 'Message: MonteCarloButton Selected', newline];
                    app.algorithmsIndex = 2;
                    app.algorithmName = 'MC';
                    app.MoreDropDown.Visible = "off";
                case app.GreedyButton
                    disp('Message: GreedyButton Selected');
%                     app.MessageTextArea.Value = [app.MessageTextArea.Value, 'Message: GreedyButton Selected', newline];
                    app.algorithmsIndex = 3;
                    app.algorithmName = 'GREEDY';
                    app.MoreDropDown.Visible = "off";
                case app.DPButton
                    disp('Message: DPButton Selected');
%                     app.MessageTextArea.Value = [app.MessageTextArea.Value, 'Message: DPButton Selected', newline];
                    app.algorithmsIndex = 4;
                    app.algorithmName = 'DP';
                    app.MoreDropDown.Visible = "off";
                case app.GAButton
                    disp('Message: GAButton Selected');
%                     app.MessageTextArea.Value = [app.MessageTextArea.Value, 'Message: GAButton Selected', newline];
                    app.algorithmsIndex = 5;
                    app.algorithmName = 'GA';
                    app.MoreDropDown.Visible = "off";
                case app.SAButton
                    disp('Message: SAButton Selected');
%                     app.MessageTextArea.Value = [app.MessageTextArea.Value, 'Message: SAButton Selected', newline];
                    app.algorithmsIndex = 6;
                    app.algorithmName = 'SA';
                    app.MoreDropDown.Visible = "off";
                case app.KNNButton
                    disp('Message: KNNButton Selected');
%                     app.MessageTextArea.Value = [app.MessageTextArea.Value, 'Message: KNNButton Selected', newline];
                    app.algorithmsIndex = 7;
                    app.algorithmName = 'KNN';
                    app.MoreDropDown.Visible = "off";
                case app.TWOOPTButton
                    disp('Message: TWOOPTButton Selected');
%                     app.MessageTextArea.Value = [app.MessageTextArea.Value, 'Message: TWOOPTButton Selected', newline];
                    app.algorithmsIndex = 8;
                    app.algorithmName = 'TWOOPT';
                    app.MoreDropDown.Visible = "off";
                case app.VNSButton
                    disp('Message: VNSButton Selected');
%                     app.MessageTextArea.Value = [app.MessageTextArea.Value, 'Message: VNSButton Selected', newline];
                    app.algorithmsIndex = 9;
                    app.algorithmName = 'VNS';
                    app.MoreDropDown.Visible = "off";
                case app.ACOButton
                    disp('Message: ACOButton Selected');
%                     app.MessageTextArea.Value = [app.MessageTextArea.Value, 'Message: ACOButton Selected', newline];
                    app.algorithmsIndex = 10;
                    app.algorithmName = 'ACO';
                    app.MoreDropDown.Visible = "off";
                case app.GurobiButton
                    disp('Message: GurobiButton Selected');
%                     app.MessageTextArea.Value = [app.MessageTextArea.Value, 'Message: GurobiButton Selected', newline];
                    app.algorithmsIndex = 11;
                    app.algorithmName = 'Gurobi';
                    app.MoreDropDown.Visible = "off";
                case app.HPSOButton
                    disp('Message: HPSOButton Selected');
                    app.algorithmsIndex = 12;
                    app.algorithmName = 'HPSO';
                    app.MoreDropDown.Visible = "off";
                case app.CWButton
                    disp('Message: CWButton Selected');
                    app.algorithmsIndex = 13;
                    app.algorithmName = 'CW';
                    app.MoreDropDown.Visible = "off";
                case app.CLIMBButton
                    disp('Message: CLIMBButton Selected');
                    app.algorithmsIndex = 14;
                    app.algorithmName = 'CLIMB';
                    app.MoreDropDown.Visible = "off";
                case app.MoreButton
                    disp('Message: MoreButton Selected');
                    app.MoreDropDown.Visible = "on";
                otherwise
                    disp('Error: Unexpected algorithms button selection changed');
%                     app.MessageTextArea.Value = [app.MessageTextArea.Value, 'Error: Unexpected algorithms button selection changed', newline];
            end

            app.isSolving = false;
            app.Lamp.Color = [0,1,0];
            app.StatusLabel.Text = 'Ready to solve';
            app.update_ButtonGroup();
        end

        % Selection changed function: LanguageButtonGroup
        function LanguageButtonGroupSelectionChanged(app, event)
            selectedButton = app.LanguageButtonGroup.SelectedObject;
            switch selectedButton % No break needed
                case app.MatlabButton
                    disp('Message: MatlabButton Selected');
                    app.languageIndex = 1;
                    app.languageName = 'MATLAB';
                case app.PythonButton
                    disp('Message: PythonButton Selected');
                    app.languageIndex = 2;
                    app.languageName = 'Python';
                otherwise
                    disp('Error: Unexpected language button selection changed');
            end
            app.update_ButtonGroup();
        end

        % Selection changed function: ProblemButtonGroup
        function ProblemButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProblemButtonGroup.SelectedObject;
            switch selectedButton % No break needed
                case app.TSPButton
                    disp('Message: TSPButton Selected');
                    app.problemIndex = 1;
                    app.problemName = 'TSP';
                case app.VRPButton
                    disp('Message: VRPButton Selected');
                    app.problemIndex = 2;
                    app.problemName = 'VRP';
                case app.KPButton
                    disp('Message: KPButton Selected');
                    app.problemIndex = 3;
                    app.problemName = 'KP';
                otherwise
                    disp('Error: Unexpected problem button selection changed');
            end
            app.update_ButtonGroup();
        end

        % Value changed function: iterationsEditField
        function iterationsEditFieldValueChanged(app, event)
            app.Data.iterations = str2double(app.iterationsEditField.Value);
        end

        % Callback function
        function PauseButtonPushed(app, event)
            if app.isSolving == true
                % stop
                app.isSolving = false;
                app.Lamp.Color = [0,1,0];
                app.StatusLabel.Text = 'Optimization Stopped';
                pause(0.1);
                app.Algo.pause();
            end
        end

        % Callback function
        function ResumeButtonPushed(app, event)
            if app.isSolving == false
                % continue
                app.isSolving = true;
                app.Lamp.Color = [1,1,0];
                app.StatusLabel.Text = 'Solving...';
                pause(0.1);
                app.Algo.resume();

                % get Data from Algo
                app.SolvedData = struct(app.Algo.get_Data());
                % get explict coordinates and graph and objVal from Data
                cx = double(app.SolvedData.cx);
                cy = double(app.SolvedData.cy);
                demand = int64(double(app.SolvedData.demand));
                xi = int64(double(app.SolvedData.xi));
                xj = int64(double(app.SolvedData.xj));
                objVal = app.Algo.get_objVal();

                % clear and hold
                cla(app.UIAxes_Result);
                hold(app.UIAxes_Result,'on');
                % draw graph only for TSP & VRP, not for KP
                if app.problemIndex ~= 3
                    G = graph(xi+1,xj+1);
                    plot(app.UIAxes_Result,G,'XData',cx,'YData',cy,'NodeFontSize',5);
                end
                % draw map
                scatter(app.UIAxes_Result,cx(1),cy(1),50,'red','filled','s');
                scatter(app.UIAxes_Result,cx(2:end),cy(2:end),20,'blue','filled');
                text(app.UIAxes_Result, cx(2:end), cy(2:end)+1, cellstr(num2str(demand(2:end)')),'Fontsize', 14); % 0.5 for a step
                % show objVal
                app.CurrentbestobjValEditField.Value = num2str(objVal);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            ExportedHistoryData = app.HistoryData;
            save('CO_SOLVER_Exported_Data','ExportedHistoryData');
        end

        % Drop down opening function: MoreDropDown
        function MoreDropDownOpening(app, event)

        end

        % Value changed function: MoreDropDown
        function MoreDropDownValueChanged(app, event)
            value = app.MoreDropDown.Value;
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.HistoryData = {};
            app.UITable.Data = {};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_Result = uiaxes(app.UIFigure);
            title(app.UIAxes_Result, 'Result Coordinates')
            xlabel(app.UIAxes_Result, 'X')
            ylabel(app.UIAxes_Result, 'Y / Value')
            zlabel(app.UIAxes_Result, 'Z')
            app.UIAxes_Data = uiaxes(app.UIFigure);
            title(app.UIAxes_Data, 'Input Coordinates')
            xlabel(app.UIAxes_Data, 'X')
            ylabel(app.UIAxes_Data, 'Y / Value')
            zlabel(app.UIAxes_Data, 'Z')
            app.UIAxes_objVal = uiaxes(app.UIFigure);
            title(app.UIAxes_objVal, 'objVal')
            xlabel(app.UIAxes_objVal, 'iter')
            ylabel(app.UIAxes_objVal, 'objVal')
            zlabel(app.UIAxes_objVal, 'Z')
            app.timeLimEditFieldLabel = uilabel(app.UIFigure);
            app.timeLimEditFieldLabel.Text = 'timeLim';
            app.CurrentbestobjValEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentbestobjValEditField.Editable = 'off';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.timeLimEditField = uieditfield(app.UIFigure, 'text');
            app.timeLimEditField.ValueChangedFcn = createCallbackFcn(app, @timeLimEditFieldValueChanged, true);
            app.iterationsEditField = uieditfield(app.UIFigure, 'text');
            app.iterationsEditField.ValueChangedFcn = createCallbackFcn(app, @iterationsEditFieldValueChanged, true);
            app.capacityEditField = uieditfield(app.UIFigure, 'text');
            app.capacityEditField.ValueChangedFcn = createCallbackFcn(app, @capacityEditFieldValueChanged, true);
            app.AlgorithmsButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgorithmsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmsButtonGroupSelectionChanged, true);
            app.AlgorithmsButtonGroup.Title = 'Algorithms';
            app.GurobiButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.GurobiButton.Text = 'Gurobi';
            app.GurobiButton.Value = true;
            app.ACOButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.ACOButton.Text = 'ACO';
            app.VNSButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.VNSButton.Text = 'VNS';
            app.TWOOPTButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.TWOOPTButton.Text = '2-OPT';
            app.KNNButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.KNNButton.Text = 'KNN';
            app.SAButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.SAButton.Text = 'SA';
            app.GAButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.GAButton.Text = 'GA';
            app.DPButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.DPButton.Text = 'DP';
            app.GreedyButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.GreedyButton.Text = 'Greedy';
            app.MonteCarloButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.MonteCarloButton.Text = 'Monte Carlo';
            app.BruteForceButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.BruteForceButton.Text = 'Brute Force';
            app.HPSOButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.HPSOButton.Text = 'HPSO';
            app.CWButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.CWButton.Text = 'CW';
            app.CLIMBButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.CLIMBButton.Text = 'CLIMB';
            app.MoreDropDown = uidropdown(app.AlgorithmsButtonGroup);
            app.MoreDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MoreDropDownOpening, true);
            app.MoreDropDown.ValueChangedFcn = createCallbackFcn(app, @MoreDropDownValueChanged, true);
            app.MoreButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.MoreButton.Text = 'More...';
            app.OpenDataButton = uibutton(app.UIFigure, 'push');
            app.OpenDataButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataButtonPushed, true);
            app.OpenDataButton.Text = 'Open Data (,mat)';
            app.ProblemButtonGroup = uibuttongroup(app.UIFigure);
            app.ProblemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProblemButtonGroupSelectionChanged, true);
            app.ProblemButtonGroup.Title = 'Problem';
            app.KPButton = uitogglebutton(app.ProblemButtonGroup);
            app.KPButton.Text = 'KP';
            app.VRPButton = uitogglebutton(app.ProblemButtonGroup);
            app.VRPButton.Text = 'VRP';
            app.VRPButton.Value = true;
            app.TSPButton = uitogglebutton(app.ProblemButtonGroup);
            app.TSPButton.Text = 'TSP';
            app.CurrentbestobjValEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentbestobjValEditFieldLabel.Text = 'Current best objVal';
            app.capacityEditFieldLabel = uilabel(app.UIFigure);
            app.capacityEditFieldLabel.Text = 'capacity';
            app.iterationsEditFieldLabel = uilabel(app.UIFigure);
            app.iterationsEditFieldLabel.Text = 'iterations';
            app.LanguageButtonGroup = uibuttongroup(app.UIFigure);
            app.LanguageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LanguageButtonGroupSelectionChanged, true);
            app.LanguageButtonGroup.Title = 'Language';
            app.PythonButton = uitogglebutton(app.LanguageButtonGroup);
            app.PythonButton.Text = 'Python';
            app.PythonButton.Value = true;
            app.MatlabButton = uitogglebutton(app.LanguageButtonGroup);
            app.MatlabButton.Text = 'Matlab';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Ready to solve';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.RuntimesEditField = uieditfield(app.UIFigure, 'text');
            app.RuntimesEditField.Editable = 'off';
            app.timeLabel = uilabel(app.UIFigure);
            app.timeLabel.Text = 'Runtime(s)';
            app.CurrentiterEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentiterEditField.Editable = 'off';
            app.CurrentiterEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentiterEditFieldLabel.Text = 'Current iter';
            app.CombinatorialOptimizationSolverv55Label = uilabel(app.UIFigure);
            app.CombinatorialOptimizationSolverv55Label.Text = 'Combinatorial Optimization Solver v5.5';
            app.CIAMLabel = uilabel(app.UIFigure);
            app.CIAMLabel.Text = 'CIAM';
            app.SolverdevelopedbyLabel = uilabel(app.UIFigure);
            app.SolverdevelopedbyLabel.Text = 'Solver developed by';
            app.CopyrightZhenkunWangSDIMSUSTechShenzhenChinaLabel = uilabel(app.UIFigure);
            app.CopyrightZhenkunWangSDIMSUSTechShenzhenChinaLabel.Text = 'Copyright: Zhenkun Wang, SDIM, SUSTech, Shenzhen, China';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Language'; 'Problem'; 'n'; 'Algorithm'; 'Iter'; 'Runtime'; 'objVal'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SolvedDataHistroyLabel = uilabel(app.UIFigure);
            app.SolvedDataHistroyLabel.Text = 'Solved Data Histroy';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '©';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CO_SOLVER_v5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end