classdef BagApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        YiChuanButton           matlab.ui.control.Button
        QiongJuButton           matlab.ui.control.Button
        DiGuiButton             matlab.ui.control.Button
        TanXinButton            matlab.ui.control.Button
        ResultEditField         matlab.ui.control.NumericEditField
        ResultEditFieldLabel    matlab.ui.control.Label
        DongTaiGuiHuaButton     matlab.ui.control.Button
        CapacityEditField       matlab.ui.control.NumericEditField
        CapacityEditFieldLabel  matlab.ui.control.Label
        PriceEditField          matlab.ui.control.EditField
        PriceEditFieldLabel     matlab.ui.control.Label
        WeightEditField         matlab.ui.control.EditField
        WeightEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        capacity int16;%背包最大容量
        cx = [];%记录物品重量的数组
        cy = [];%记录物品价值的数组
        objVal double;%被选择物品的总价值
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DongTaiGuiHuaButton
        function DongTaiGuiHuaButtonPushed(app, event)
            app.capacity = app.CapacityEditField.Value;
            weightStr = app.WeightEditField.Value;
            app.cx = [0,str2num(weightStr)];
            priceStr = app.PriceEditField.Value;
            app.cy = [0,str2num(priceStr)];
            
            n = length(app.cx);% n为物品的个数
            distance = [];%定义状态转移矩阵存储选中物品总价值
            xi = [];%背包里物品的状态，0为取，1为不取
            app.objVal = 0;%被选择物品的总价值

            %判断第一个物品放或不放；
            for j=1:(app.capacity+1)
                if app.cx(n)<j
                    distance(n,j)=app.cy(n) ;
                else
                    distance(n,j)=0;
                end
            end

            %判断下一个物品是放还是不放；不放时：F[i,v]=F[i-1,v]；放时：F[i,v]=max{F[i-1,v],F[i-1,v-C_i]+w_i}；
            for i=n-1:-1:1
                for j=1:(app.capacity+1)
                    if j<=app.cx(i)
                        distance(i,j)=distance(i+1,j);
                    else
                        if distance(i+1,j)>distance(i+1,j-app.cx(i))+app.cy(i)
                            distance(i,j)=distance(i+1,j);
                        else
                            distance(i,j)=distance(i+1,j-app.cx(i))+app.cy(i);
                        end
                    end
                end
            end

            %确定被选择的物品。
            i=app.capacity;
            for j=1:n-1
                if distance(j,i)==distance(j+1,i)
                    xi(j)=0;
                else
                    xi(j)=1;
                    i=i-app.cx(j);
                end
            end
            if distance(n,i)==0
                xi(n)=0;
            else
                xi(n)=1;
        
            end

            %计算被选择物品的总价值
            for i=2:n
                if(xi(i)==1)
                    app.objVal=app.objVal+app.cy(i);
                end
            end

            app.ResultEditField.Value = app.objVal;

            %版权声明：本文为CSDN博主「Tsroad」的原创文章，遵循CC 4.0 BY-SA版权协议，转载请附上原文出处链接及本声明。
            %原文链接：https://blog.csdn.net/tsroad/article/details/52048562
            %本代码对原文语法、变量名、输入输出、运算处理进行了部分改动
        end

        % Button pushed function: TanXinButton
        function TanXinButtonPushed(app, event)
            app.capacity = app.CapacityEditField.Value;
            weightStr = app.WeightEditField.Value;
            app.cx = str2num(weightStr);
            priceStr = app.PriceEditField.Value;
            app.cy = str2num(priceStr);
            
            n = length(app.cx);% n为物品的个数
            v = [];%背包里物品的状态，0为取，1为不取
            app.objVal = 0;%被选择物品的总价值
            a=[];%物品单位重量的价值，其中1号位置不使用
            v=[];%背包里物品的状态，0为取，1为不取

            %计算物品单位重量的价值并降序排列，记录序号
            for i=1:n
                a(i)=app.cy(i)/app.cx(i);
            end
            [~,place]=sort(a,"descend");

            %在满足选取物品总重量不超过背包容量的前提下，优先选择单位重量价值较大的物品
            for i=1:n
                app.objVal=app.objVal+app.cy(place(i));
                app.capacity=app.capacity-app.cx(place(i));
                if(app.capacity<0)
                    app.objVal=app.objVal-app.cy(place(i));
                    app.capacity=app.capacity+app.cx(place(i));
                else
                    v(place(i))=1;
                end
            end

            app.ResultEditField.Value = app.objVal;

            %原文链接：https://ishare.iask.sina.com.cn/f/14405145.html
            %本代码对原文语法、变量名、输入输出、运算处理进行了部分改动
        end

        % Button pushed function: DiGuiButton
        function DiGuiButtonPushed(app, event)
            app.capacity = app.CapacityEditField.Value;
            weightStr = app.WeightEditField.Value;
            app.cx = str2num(weightStr);
            priceStr = app.PriceEditField.Value;
            app.cy = str2num(priceStr);
            
            n =length(app.cx);% n为物品的个数

            %利用递归函数求出不超过背包容量的可选择物品的最大价值总和
            app.objVal=getM(n,app.capacity,app.cx,app.cy);

            app.ResultEditField.Value = app.objVal;

            %版权声明：本文为CSDN博主「gx_up」的原创文章，遵循CC 4.0 BY-SA版权协议，转载请附上原文出处链接及本声明。
            %原文链接：https://blog.csdn.net/qq_32688731/article/details/54025607
            %本代码对原文语法、变量名、输入输出、运算处理进行了部分改动
        end

        % Button pushed function: QiongJuButton
        function QiongJuButtonPushed(app, event)
            app.capacity = app.CapacityEditField.Value;
            weightStr = app.WeightEditField.Value;
            app.cx = str2num(weightStr);
            priceStr = app.PriceEditField.Value;
            app.cy = str2num(priceStr);

            n =length(app.cx);% n为物品的个数
            v=[];%背包里物品的状态，0为取，1为不取
            app.objVal=0;%被选择物品的总价值

            for i=0:2^n-1
                v=dec2bin(i,n);%使用n位二进制数表示物品取或不取
                temp_w=0;
                temp_p=0;
                for j=1:n
                    if v(j)=='1'
                        temp_w=temp_w+app.cx(j);
                        temp_p=temp_p+app.cy(j);
                    end%计算当前情况的总价值和总重量
                    if (temp_w<=app.capacity)&&(temp_p>app.objVal)
                        app.objVal=temp_p;
                    end%找出最大价值
                end
            end

            app.ResultEditField.Value = app.objVal;

            %原文链接：https://zhidao.baidu.com/question/54334169.html
            %本代码对原文语法、变量名、输入输出、运算处理进行了部分改动
        end

        % Button pushed function: YiChuanButton
        function YiChuanButtonPushed(app, event)
            app.capacity = app.CapacityEditField.Value;
            weightStr = app.WeightEditField.Value;
            app.cx = str2num(weightStr);
            priceStr = app.PriceEditField.Value;
            app.cy = str2num(priceStr);

            n = length(app.cx);
            p1 = .95; %交叉概率
            p2 = .15; %变异概率
            max_diedai = 100; %最大迭代次数

            %构建初始种群 
            zhongqun_nums = 20;
            jiyin = zeros(1,n);

            rand('state',sum(clock));
            zhongqun1 = zeros(zhongqun_nums,n);
            ave_value = zeros(1,max_diedai);
            max_value = zeros(1,max_diedai);
            for i = 1:zhongqun_nums
                jiyin = round(rand(1,n));
                while jiyin * app.cx' > app.capacity
                    jiyin = round(rand(1,n));
                end
                zhongqun1(i,:) = jiyin;
            end
            for i = 1: max_diedai
            %交叉：单点
                zhongqun2 = zhongqun1;
                for k = 1: 2 : zhongqun_nums
                    if rand < p1 %判断是否交叉
                        pos = ceil(n*rand); %交叉位置
                        temp1 = zhongqun2(k,:);
                        temp2 = zhongqun2(k+1,:);
                        temp = temp1(pos);
                        temp1(pos) = temp2(pos);
                        temp2(pos) = temp;
                        if temp1 * app.cx' <= app.capacity && temp2 * app.cx'  <= app.capacity
                            zhongqun2(k,:) = temp1;
                            zhongqun2(k+1,:) = temp2;
                        end
                    end
                end
                    %变异
                    zhongqun3 = zhongqun1; %与交叉同等地位
                    for k = 1:zhongqun_nums
                        if rand < p2
                            pos = ceil(n*rand);
                            temp = zhongqun3(k,:);
                            temp(pos) = ~temp(pos);
                            if  temp * app.cx' <= app.capacity
                                zhongqun3(k,:) = temp;
                            end
                        end
                    end
                    %选择 
                    % 价值最大的前zhongqun_nums个
                    zhongqun = [zhongqun1;zhongqun2;zhongqun3];
                    temp_value = zhongqun*(app.cy');
                    [~,index] = sort(temp_value,'descend');
                    ave_value(i) = sum(zhongqun(index(1:zhongqun_nums),:)*app.cy')/zhongqun_nums;
                    max_value(i) = zhongqun(index(1),:)*app.cy';
                    zhongqun1 = zhongqun(index(1:zhongqun_nums),:);
            end

            app.objVal=zhongqun(1,:)*app.cy';

            app.ResultEditField.Value = app.objVal;

            %版权声明：本文为CSDN博主「一夜星尘」的原创文章，遵循CC 4.0 BY-SA版权协议，转载请附上原文出处链接及本声明。
            %原文链接：https://blog.csdn.net/weixin_43914658/article/details/108610906
            %本代码对原文语法、变量名、输入输出、运算处理进行了部分改动
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WeightEditFieldLabel = uilabel(app.UIFigure);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.UIFigure, 'text');
            app.PriceEditFieldLabel = uilabel(app.UIFigure);
            app.PriceEditFieldLabel.Text = 'Price';
            app.PriceEditField = uieditfield(app.UIFigure, 'text');
            app.CapacityEditFieldLabel = uilabel(app.UIFigure);
            app.CapacityEditFieldLabel.Text = 'Capacity';
            app.CapacityEditField = uieditfield(app.UIFigure, 'numeric');
            app.DongTaiGuiHuaButton = uibutton(app.UIFigure, 'push');
            app.DongTaiGuiHuaButton.ButtonPushedFcn = createCallbackFcn(app, @DongTaiGuiHuaButtonPushed, true);
            app.DongTaiGuiHuaButton.Text = 'DongTaiGuiHua';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'numeric');
            app.TanXinButton = uibutton(app.UIFigure, 'push');
            app.TanXinButton.ButtonPushedFcn = createCallbackFcn(app, @TanXinButtonPushed, true);
            app.TanXinButton.Text = 'TanXin';
            app.DiGuiButton = uibutton(app.UIFigure, 'push');
            app.DiGuiButton.ButtonPushedFcn = createCallbackFcn(app, @DiGuiButtonPushed, true);
            app.DiGuiButton.Text = 'DiGui';
            app.QiongJuButton = uibutton(app.UIFigure, 'push');
            app.QiongJuButton.ButtonPushedFcn = createCallbackFcn(app, @QiongJuButtonPushed, true);
            app.QiongJuButton.Text = 'QiongJu';
            app.YiChuanButton = uibutton(app.UIFigure, 'push');
            app.YiChuanButton.ButtonPushedFcn = createCallbackFcn(app, @YiChuanButtonPushed, true);
            app.YiChuanButton.Text = 'YiChuan';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BagApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end