classdef MatlabSolver_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LanguageButtonGroup       matlab.ui.container.ButtonGroup
        MatlabButton              matlab.ui.control.ToggleButton
        PythonButton              matlab.ui.control.ToggleButton
        iterationsEditFieldLabel  matlab.ui.control.Label
        capacityEditFieldLabel    matlab.ui.control.Label
        ObjValEditFieldLabel      matlab.ui.control.Label
        ProblemButtonGroup        matlab.ui.container.ButtonGroup
        TSPButton                 matlab.ui.control.ToggleButton
        VRPButton                 matlab.ui.control.ToggleButton
        KPButton                  matlab.ui.control.ToggleButton
        OpenDataButton            matlab.ui.control.Button
        AlgorithmsButtonGroup     matlab.ui.container.ButtonGroup
        BruteForceButton          matlab.ui.control.RadioButton
        MonteCarloButton          matlab.ui.control.RadioButton
        GreedyButton              matlab.ui.control.RadioButton
        DPButton                  matlab.ui.control.RadioButton
        GAButton                  matlab.ui.control.RadioButton
        SAButton                  matlab.ui.control.RadioButton
        KNNButton                 matlab.ui.control.RadioButton
        TWOOPTButton              matlab.ui.control.RadioButton
        VNSButton                 matlab.ui.control.RadioButton
        ACOButton                 matlab.ui.control.RadioButton
        GurobiButton              matlab.ui.control.RadioButton
        capacityEditField         matlab.ui.control.EditField
        iterationsEditField       matlab.ui.control.EditField
        timeLimEditField          matlab.ui.control.EditField
        SolveButton               matlab.ui.control.Button
        ObjValEditField           matlab.ui.control.EditField
        ResultTextArea            matlab.ui.control.TextArea
        timeLimEditFieldLabel     matlab.ui.control.Label
        UIAxes_Status             matlab.ui.control.UIAxes
        UIAxes_Data               matlab.ui.control.UIAxes
        UIAxes_Result             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data; % Description
        dairy;
        Algorithms = {{0, 0, 0, 0, 0, 0, 0, 0, 0, 0;
                      0, 0, 0, 0, 0, 0, 0, 0, 0, 0;
                      0, 0, 0, 0, 0, 0, 0, 0, 0, 0;}, {0, 0, 0, 0, 0, 0, 0, 0, 0, 0, py.Gurobi_Class.Gurobi()}};
%         Algorithms = {py.VRP_Python_Class_Gurobi.VRPGurobi()};
        problemIndex = 2;
        algorithmsIndex = 11;
        languageIndex = 2;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenDataButton
        function OpenDataButtonPushed(app, event)
%             disp('Message: OpenDataButtonPushed');
%             if app.languageIndex == 1 % Matlab
%                 filename = uigetfile('.mat', 'Select .mat data');
%                 app.Data = load(filename).Data;
% 
%                 cla(app.UIAxes_Data)
%                 hold(app.UIAxes_Data,'on')
%                 scatter(app.UIAxes_Data,app.Data.cx(1),app.Data.cy(1),50,'red','filled','s');
%                 scatter(app.UIAxes_Data,app.Data.cx(2:end),app.Data.cy(2:end),20,'blue','filled');
% %                 text(app.UIAxes_Data,app.Data.xc(2:end)-0.4, app.Data.yc(2:end)+1, strcat('d: ',cellstr(num2str(app.Data.Dmd(2:end)'))),'Fontsize', 10);
%                 text(app.UIAxes_Data,app.Data.cx(2:end), app.Data.cy(2:end), cellstr(num2str(app.Data.demand(2:end)')),'Fontsize', 14);
% 
%                 app.capacityEditField.Value = num2str(app.Data.capacity);
%                 app.timeLimEditField.Value = num2str(app.Data.timeLim);
%             elseif app.languageIndex == 2 % Python
%                 filename = uigetfile('.mat', 'Select .mat data');
%                 app.Data = load(filename).Data;
% 
%                 cla(app.UIAxes_Data)
%                 hold(app.UIAxes_Data,'on')
%                 scatter(app.UIAxes_Data,app.Data.cx(1),app.Data.cy(1),50,'red','filled','s');
%                 scatter(app.UIAxes_Data,app.Data.cx(2:end),app.Data.cy(2:end),20,'blue','filled');
% %                 text(app.UIAxes_Data,app.Data.xc(2:end)-0.4, app.Data.yc(2:end)+1, strcat('d: ',cellstr(num2str(app.Data.Dmd(2:end)'))),'Fontsize', 10);
%                 text(app.UIAxes_Data,app.Data.cx(2:end), app.Data.cy(2:end), cellstr(num2str(app.Data.demand(2:end)')),'Fontsize', 14);
% 
%                 app.capacityEditField.Value = num2str(app.Data.capacity);
%                 app.iterationsEditField.Value = num2str(app.Data.iterations);
%                 app.timeLimEditField.Value = num2str(app.Data.timeLim);
%             else
%                 disp('Error: languageIndex is not 1 or 2, it is ', num2str(app.languageIndex));
%             end

                filename = uigetfile('.mat', 'Select .mat data');
                app.Data = load(filename).Data;

                cla(app.UIAxes_Data)
                hold(app.UIAxes_Data,'on')
                scatter(app.UIAxes_Data,app.Data.cx(1),app.Data.cy(1),50,'red','filled','s');
                scatter(app.UIAxes_Data,app.Data.cx(2:end),app.Data.cy(2:end),20,'blue','filled');
%                 text(app.UIAxes_Data,app.Data.xc(2:end)-0.4, app.Data.yc(2:end)+1, strcat('d: ',cellstr(num2str(app.Data.Dmd(2:end)'))),'Fontsize', 10);
                text(app.UIAxes_Data,app.Data.cx(2:end), app.Data.cy(2:end), cellstr(num2str(app.Data.demand(2:end)')),'Fontsize', 14);

                app.capacityEditField.Value = num2str(app.Data.capacity);
                app.iterationsEditField.Value = num2str(app.Data.iterations);
                app.timeLimEditField.Value = num2str(app.Data.timeLim);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
%             diary("Status_Output(MatlabSoloverPython)");
%             diary on;
% 
%             VRPGurobi = app.Algorithms{2}{11};
%             VRPGurobi.set_all(app.Data.n, app.Data.capacity, app.Data.demand, app.Data.cx, app.Data.cy, app.Data.timeLim);
%             VRPGurobi.optimize();
%             xc = double(VRPGurobi.get_xc());
%             yc = double(VRPGurobi.get_yc());
%             active_ci = int64(double(VRPGurobi.get_active_ci()));
%             active_cj = int64(double(VRPGurobi.get_active_cj()));
%             objVal = VRPGurobi.get_objVal();
% 
%             G = graph(active_ci+1,active_cj+1);
%             cla(app.UIAxes_Result);
%             hold(app.UIAxes_Result,'on');
%             plot(app.UIAxes_Result,G,'XData',xc,'YData',yc,'NodeFontSize',5);
%             scatter(app.UIAxes_Result,xc(1),yc(1),50,'red','filled','s');
%             scatter(app.UIAxes_Result,xc(2:end),yc(2:end),20,'blue','filled');
%             text(app.UIAxes_Result,app.Data.cx(2:end), app.Data.cy(2:end)+1, cellstr(num2str(app.Data.demand(2:end)')),'Fontsize', 14); % 0.5 for a step
%             app.ObjValEditField.Value = num2str(objVal);
% 
%             diary off;
%             app.dairy=fileread('Status_Output(MatlabSoloverPython)');
%             app.ResultTextArea.Value = app.dairy;
%             delete('Status_Output(MatlabSoloverPython)');

            diary("diary_Output(MatlabSolver_v2)");
            diary on;

            % get algorithm
            Algorithm = app.Algorithms{app.languageIndex}{app.algorithmsIndex};
            % get solved Data
            app.Data = struct(Algorithm.solve(app.Data));
            % get explict coordinates and graph and objVal from Data
            cx = double(app.Data.cx);
            cy = double(app.Data.cy);
            demand = int64(double(app.Data.demand));
            xi = int64(double(app.Data.xi));
            xj = int64(double(app.Data.xj));
            objVal = Algorithm.get_objVal();

            % clear and hold
            cla(app.UIAxes_Result);
            hold(app.UIAxes_Result,'on');

            % draw graph only for TSP & VRP, not for KP
            if app.problemIndex ~= 3
                G = graph(xi+1,xj+1);
                plot(app.UIAxes_Result,G,'XData',cx,'YData',cy,'NodeFontSize',5);
            end

            % draw map
            scatter(app.UIAxes_Result,cx(1),cy(1),50,'red','filled','s');
            scatter(app.UIAxes_Result,cx(2:end),cy(2:end),20,'blue','filled');
            text(app.UIAxes_Result, cx(2:end), cy(2:end)+1, cellstr(num2str(demand(2:end)')),'Fontsize', 14); % 0.5 for a step

            % show objVal
            app.ObjValEditField.Value = num2str(objVal);

            diary off;
            app.dairy=fileread('diary_Output(MatlabSolver_v2)');
            app.ResultTextArea.Value = app.dairy;
            delete('diary_Output(MatlabSolver_v2');
        end

        % Value changed function: capacityEditField
        function capacityEditFieldValueChanged(app, event)
            app.Data.Capacity = str2double(app.capacityEditField.Value);
        end

        % Value changed function: timeLimEditField
        function timeLimEditFieldValueChanged(app, event)
            app.Data.TimeLimit = str2double(app.timeLimEditField.Value);
        end

        % Selection changed function: AlgorithmsButtonGroup
        function AlgorithmsButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlgorithmsButtonGroup.SelectedObject;
            switch selectedButton % No break needed
                case app.BruteForceButton
                    disp('Message: BruteForceButton Selected');
                    app.algorithmsIndex = 1;
                case app.MonteCarloButton
                    disp('Message: MonteCarloButton Selected');
                    app.algorithmsIndex = 2;
                case app.GreedyButton
                    disp('Message: GreedyButton Selected');
                    app.algorithmsIndex = 3;
                case app.DPButton
                    disp('Message: DPButton Selected');
                    app.algorithmsIndex = 4;
                case app.GAButton
                    disp('Message: GAButton Selected');
                    app.algorithmsIndex = 5;
                case app.SAButton
                    disp('Message: SAButton Selected');
                    app.algorithmsIndex = 6;
                case app.KNNButton
                    disp('Message: KNNButton Selected');
                    app.algorithmsIndex = 7;
                case app.TWOOPTButton
                    disp('Message: TWOOPTButton Selected');
                    app.algorithmsIndex = 8;
                case app.VNSButton
                    disp('Message: VNSButton Selected');
                    app.algorithmsIndex = 9;
                case app.ACOButton
                    disp('Message: ACOButton Selected');
                    app.algorithmsIndex = 10;
                case app.GurobiButton
                    disp('Message: Gurobi Selected');
                    app.algorithmsIndex = 11;
                otherwise
                    disp('Error: Unexpected algorithms button selection changed');
            end
            disp(['Message: algorithmsIndex is now: ', num2str(app.algorithmsIndex)]);
            
        end

        % Selection changed function: LanguageButtonGroup
        function LanguageButtonGroupSelectionChanged(app, event)
            selectedButton = app.LanguageButtonGroup.SelectedObject;
            switch selectedButton % No break needed
                case app.MatlabButton
                    disp('Message: MatlabButton Selected');
                    app.languageIndex = 1;
                case app.PythonButton
                    disp('Message: PythonButton Selected');
                    app.languageIndex = 2;
                otherwise
                    disp('Error: Unexpected language button selection changed');
            end
            disp(['Message: languageIndex is now: ', num2str(app.languageIndex)]);
        end

        % Selection changed function: ProblemButtonGroup
        function ProblemButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProblemButtonGroup.SelectedObject;
            switch selectedButton % No break needed
                case app.TSPButton
                    disp('Message: TSPButton Selected');
                    app.problemIndex = 1;
                case app.VRPButton
                    disp('Message: VRPButton Selected');
                    app.problemIndex = 2;
                case app.KPButton
                    disp('Message: KPButton Selected');
                    app.problemIndex = 3;
                otherwise
                    disp('Error: Unexpected problem button selection changed');
            end
            disp(['Message: problemIndex is now: ', num2str(app.problemIndex)]);

            
        end

        % Value changed function: iterationsEditField
        function iterationsEditFieldValueChanged(app, event)
            app.Data.iterations = str2double(app.iterationsEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Result = uiaxes(app.UIFigure);
            title(app.UIAxes_Result, 'Result Coordinates')
            xlabel(app.UIAxes_Result, 'X')
            ylabel(app.UIAxes_Result, 'Y / Value')
            zlabel(app.UIAxes_Result, 'Z')
            app.UIAxes_Data = uiaxes(app.UIFigure);
            title(app.UIAxes_Data, 'Input Coordinates')
            xlabel(app.UIAxes_Data, 'X')
            ylabel(app.UIAxes_Data, 'Y / Value')
            zlabel(app.UIAxes_Data, 'Z')
            app.UIAxes_Status = uiaxes(app.UIFigure);
            title(app.UIAxes_Status, 'Status')
            xlabel(app.UIAxes_Status, 'X')
            ylabel(app.UIAxes_Status, 'Y')
            zlabel(app.UIAxes_Status, 'Z')
            app.timeLimEditFieldLabel = uilabel(app.UIFigure);
            app.timeLimEditFieldLabel.Text = 'timeLim';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.ResultTextArea.Editable = 'off';
            app.ResultTextArea.Placeholder = 'Output Messages Window';
            app.ObjValEditField = uieditfield(app.UIFigure, 'text');
            app.ObjValEditField.Editable = 'off';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.timeLimEditField = uieditfield(app.UIFigure, 'text');
            app.timeLimEditField.ValueChangedFcn = createCallbackFcn(app, @timeLimEditFieldValueChanged, true);
            app.timeLimEditField.Value = '60';
            app.iterationsEditField = uieditfield(app.UIFigure, 'text');
            app.iterationsEditField.ValueChangedFcn = createCallbackFcn(app, @iterationsEditFieldValueChanged, true);
            app.iterationsEditField.Value = '60';
            app.capacityEditField = uieditfield(app.UIFigure, 'text');
            app.capacityEditField.ValueChangedFcn = createCallbackFcn(app, @capacityEditFieldValueChanged, true);
            app.capacityEditField.Value = '100';
            app.AlgorithmsButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgorithmsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmsButtonGroupSelectionChanged, true);
            app.AlgorithmsButtonGroup.Title = 'Algorithms';
            app.GurobiButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.GurobiButton.Text = 'Gurobi';
            app.GurobiButton.Value = true;
            app.ACOButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.ACOButton.Text = 'ACO';
            app.VNSButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.VNSButton.Text = 'VNS';
            app.TWOOPTButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.TWOOPTButton.Text = '2-OPT';
            app.KNNButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.KNNButton.Text = 'KNN';
            app.SAButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.SAButton.Text = 'SA';
            app.GAButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.GAButton.Text = 'GA';
            app.DPButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.DPButton.Text = 'DP';
            app.GreedyButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.GreedyButton.Text = 'Greedy';
            app.MonteCarloButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.MonteCarloButton.Text = 'Monte Carlo';
            app.BruteForceButton = uiradiobutton(app.AlgorithmsButtonGroup);
            app.BruteForceButton.Text = 'Brute Force';
            app.OpenDataButton = uibutton(app.UIFigure, 'push');
            app.OpenDataButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataButtonPushed, true);
            app.OpenDataButton.Text = 'Open Data (,mat)';
            app.ProblemButtonGroup = uibuttongroup(app.UIFigure);
            app.ProblemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProblemButtonGroupSelectionChanged, true);
            app.ProblemButtonGroup.Title = 'Problem';
            app.KPButton = uitogglebutton(app.ProblemButtonGroup);
            app.KPButton.Text = 'KP';
            app.VRPButton = uitogglebutton(app.ProblemButtonGroup);
            app.VRPButton.Text = 'VRP';
            app.VRPButton.Value = true;
            app.TSPButton = uitogglebutton(app.ProblemButtonGroup);
            app.TSPButton.Text = 'TSP';
            app.ObjValEditFieldLabel = uilabel(app.UIFigure);
            app.ObjValEditFieldLabel.Text = 'ObjVal';
            app.capacityEditFieldLabel = uilabel(app.UIFigure);
            app.capacityEditFieldLabel.Text = 'capacity';
            app.iterationsEditFieldLabel = uilabel(app.UIFigure);
            app.iterationsEditFieldLabel.Text = 'iterations';
            app.LanguageButtonGroup = uibuttongroup(app.UIFigure);
            app.LanguageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LanguageButtonGroupSelectionChanged, true);
            app.LanguageButtonGroup.Title = 'Language';
            app.PythonButton = uitogglebutton(app.LanguageButtonGroup);
            app.PythonButton.Text = 'Python';
            app.PythonButton.Value = true;
            app.MatlabButton = uitogglebutton(app.LanguageButtonGroup);
            app.MatlabButton.Text = 'Matlab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabSolver_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end